classdef Interfaz_EMG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        RegresarButton                  matlab.ui.control.Button
        BCIEMGUVGLabel                  matlab.ui.control.Label
        PaneldeControl                  matlab.ui.container.Panel
        InicializarSwitchLabel          matlab.ui.control.Label
        InicializarSwitch               matlab.ui.control.Switch
        ParametersPanel                 matlab.ui.container.Panel
        SPEEDEditFieldLabel             matlab.ui.control.Label
        SPEEDEditField                  matlab.ui.control.NumericEditField
        COMDropDownLabel                matlab.ui.control.Label
        COMDropDown                     matlab.ui.control.DropDown
        ButtonsPanel                    matlab.ui.container.Panel
        STARTButton                     matlab.ui.control.Button
        STOPButton                      matlab.ui.control.Button
        LampGreen                       matlab.ui.control.Lamp
        LampRed                         matlab.ui.control.Lamp
        Muestra1CanalButton             matlab.ui.control.Button
        Muestra2CanalesButton           matlab.ui.control.Button
        TiempodemuestraEditFieldLabel   matlab.ui.control.Label
        TiempodemuestraEditField        matlab.ui.control.NumericEditField
        FechaLabel                      matlab.ui.control.Label
        FechaEditField                  matlab.ui.control.EditField
        HoraLabel                       matlab.ui.control.Label
        HoraEditField                   matlab.ui.control.EditField
        SealesentiempoRealPanel         matlab.ui.container.Panel
        TiempoEditFieldLabel            matlab.ui.control.Label
        TiempoEditField                 matlab.ui.control.EditField
        Lamp                            matlab.ui.control.Lamp
        figFpz                          matlab.ui.control.UIAxes
        figFpz_2                        matlab.ui.control.UIAxes
        DesplieguedeCaractersticasentiemporealPanel  matlab.ui.container.Panel
        Canal1Label                     matlab.ui.control.Label
        EnhancedWaveLengthLabel         matlab.ui.control.Label
        EnhancedWaveLengthEditField     matlab.ui.control.NumericEditField
        WaveformLengthEditFieldLabel    matlab.ui.control.Label
        WaveformLengthEditField         matlab.ui.control.NumericEditField
        MaximumFractalLengthEditFieldLabel  matlab.ui.control.Label
        MaximumFractalLengthEditField   matlab.ui.control.NumericEditField
        skewnessEditFieldLabel          matlab.ui.control.Label
        skewnessEditField               matlab.ui.control.NumericEditField
        Canal2Label                     matlab.ui.control.Label
        EnhancedWaveLengthEditField_2Label  matlab.ui.control.Label
        EnhancedWaveLengthEditField_2   matlab.ui.control.NumericEditField
        WaveformLengthEditField_2Label  matlab.ui.control.Label
        WaveformLengthEditField_2       matlab.ui.control.NumericEditField
        MaximumFractalLengthEditField_2Label  matlab.ui.control.Label
        MaximumFractalLengthEditField_2  matlab.ui.control.NumericEditField
        skewnessEditField_2Label        matlab.ui.control.Label
        skewnessEditField_2             matlab.ui.control.NumericEditField
        CLASEEditFieldLabel             matlab.ui.control.Label
        CLASEEditField                  matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function RegresarButtonPushed(app, event)
             Interfaz_Inicio
             delete(app)
        end

        % Value changed function: InicializarSwitch
        function InicializarSwitchValueChanged(app, event)
            
            
            load('data_mod')
            global Epocas 
            global Tiempo_DMuestra
            global data_n
            global h1
            global c
            
            
            c = clock;
            app.FechaEditField.Value =  string(c(3))+"/" + string(c(2)) + "/" +  string(c(1)) 
            app.HoraEditField.Value  =  string(c(4))+":" + string(c(5))  
            F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda       
            F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda 
            app.Lamp.Enable = 'off';


        end

        % Callback function
        function ButtonPushed(app, event)
            global R17
            q1 = [0; 0;90; 0; -45; 0];
            R17fKine(R17, q1);
        end

        % Button pushed function: RegresarButton
        function RegresarButtonPushed2(app, event)
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            global R17
            global COM
            global SPEED
            SPEED = app.SPEEDEditField.Value
            app.LampGreen.Enable = 'on';
            app.LampRed.Enable = 'off';
            app.STOPButton.Enable = 'on';
            app.STARTButton.Enable = 'off';
            R17 = R17Init(COM, SPEED);
            assignin('base', 'R17', R17);
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.LampGreen.Enable = 'off';
            app.LampRed.Enable = 'on';
            app.STOPButton.Enable = 'off';
            app.STARTButton.Enable = 'on';
            global R17
            %             q = [0; 0; 0; 0; 0; 0];
            %             app.fK = R17fKine(app.R17, q);
%             R17Command(app.R17, 'HOME');
            %             pause(10);
            R17Close(R17);
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
            global COM
            COM = app.COMDropDown.Value;
            % Para visualizar en el workspace
            assignin('base', 'com', COM);
        end

        % Callback function
        function Movement1ButtonPushed(app, event)
            global R17 
            q2 = [0; 0;deg2rad(90); 0; deg2rad(-45); 0];
            R17fKine(R17, q2);
        end

        % Callback function
        function Movement2ButtonPushed(app, event)
             global R17
            q1 = [0; deg2rad(90);0; deg2rad(45); 0; 0];
            R17fKine(R17, q1);
        end

        % Value changed function: SPEEDEditField
        function SPEEDEditFieldValueChanged(app, event)
            
            
        end

        % Button pushed function: Muestra1CanalButton
        function Muestra1CanalButtonPushed(app, event)


                %Extracción características
                global Data
                global data_n
                global Tiempo_DMuestra
                global h1
                global time 
                time = 0;
                load('TreeCanal5Train.mat')
                load('DataTabla.mat')
                Tiempo_DMuestra = app.TiempodemuestraEditField.Value;
                b = Bitalino;
                b.startBackground;
                % Pause to acquire data for 20 seconds
                %pause(10);
                % Read the data from the device
                
                
                app.Lamp.Enable = 'on';
            
                
                'HELLOO'
                %data = readCurrentValues(b, 5000)*5/255;
                while (time < Tiempo_DMuestra)
                    pause(1)
                    time = time + 1
                    app.TiempoEditField.Value = string(time)
                end

                %pause(Tiempo_DMuestra);
                % Read the data from the device
                data = b.read*5/1024;       
                %hola =          data(:,2)
                % Stop background acquisition of data
                b.stopBackground;
                % Clean up the bitalino object
                delete(b)


                app.Lamp.Enable = 'off';
                
                F_pb = filtro_pasa_banda(1000,20,450);    %Diseñar filtro pasa banda       
                F_notch = filtro_rechaza_banda(1000);     %Diseñar filtro rechaza banda 
                data_f = filter(F_pb, data(:,6)');                          %Aplicar filtro pasa bandas
                data_n = filter(F_notch, data_f);                       %Aplicar filtro notch
                Epocas = length(data_n);

                Data.WaveC5EMG(1) = jEnhancedWaveLength(data_n);
                Data.WaveFormC5EMG(1)= jWaveformLength(data_n);
                Data.MaximumFC5EMG(1) = jMaximumFractalLength(data_n);
                Data.SkewnessFC5EMG(1) = jSkewness(data_n);
       
                app.EnhancedWaveLengthEditField.Value = Data.WaveC5EMG(1);
                app.WaveformLengthEditField.Value = Data.WaveFormC5EMG(1);
                app.MaximumFractalLengthEditField.Value = Data.MaximumFC5EMG(1);
                app.skewnessEditField.Value = Data.SkewnessFC5EMG(1);
                
                L = Epocas;
                t= linspace( 0, Epocas/1000, L);
                h1 = plot( app.figFpz_2,t, data_n)
                ylim(app.figFpz_2,[min(data_n) max(data_n)]);
                h1.YData = data_n
        
                   
                yfit = TREE_Canal5EMG.predictFcn(Data)
                        switch yfit
                        case 'Left'
                             %q1 = [0; 0;90; 0; -45; 0];
                             %R17fKine(app.R17, q1);
                             app.CLASEEditField.Value = "Left"
                        case 'Forward'
                             %q2 = [0; 60;0; 45;90; 0];
                             %R17fKine(app.R17, q2);
                             app.CLASEEditField.Value = "Forward"
                    end 
                 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RegresarButton = uibutton(app.UIFigure, 'push');
            app.RegresarButton.ButtonPushedFcn = createCallbackFcn(app, @RegresarButtonPushed2, true);
            app.RegresarButton.Icon = 'back.png';
            app.RegresarButton.Text = 'Regresar';
            app.BCIEMGUVGLabel = uilabel(app.UIFigure);
            app.BCIEMGUVGLabel.Text = 'BCI EMG - UVG';
            app.PaneldeControl = uipanel(app.UIFigure);
            app.PaneldeControl.Title = 'Panel de Control';
            app.InicializarSwitchLabel = uilabel(app.PaneldeControl);
            app.InicializarSwitchLabel.Text = 'Inicializar';
            app.InicializarSwitch = uiswitch(app.PaneldeControl, 'slider');
            app.InicializarSwitch.ValueChangedFcn = createCallbackFcn(app, @InicializarSwitchValueChanged, true);
            app.ParametersPanel = uipanel(app.PaneldeControl);
            app.ParametersPanel.Title = 'Parameters Panel';
            app.SPEEDEditFieldLabel = uilabel(app.ParametersPanel);
            app.SPEEDEditFieldLabel.Text = 'SPEED';
            app.SPEEDEditField = uieditfield(app.ParametersPanel, 'numeric');
            app.SPEEDEditField.Limits = [5000 25000];
            app.SPEEDEditField.RoundFractionalValues = 'on';
            app.SPEEDEditField.ValueChangedFcn = createCallbackFcn(app, @SPEEDEditFieldValueChanged, true);
            app.SPEEDEditField.Value = 10000;
            app.COMDropDownLabel = uilabel(app.ParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.ParametersPanel);
            app.COMDropDown.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'COM1';
            app.ButtonsPanel = uipanel(app.PaneldeControl);
            app.ButtonsPanel.Title = 'Buttons Panel';
            app.STARTButton = uibutton(app.ButtonsPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.ButtonsPanel, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.LampGreen = uilamp(app.ButtonsPanel);
            app.LampRed = uilamp(app.ButtonsPanel);
            app.Muestra1CanalButton = uibutton(app.PaneldeControl, 'push');
            app.Muestra1CanalButton.ButtonPushedFcn = createCallbackFcn(app, @Muestra1CanalButtonPushed, true);
            app.Muestra1CanalButton.Text = 'Muestra 1 Canal';
            app.Muestra2CanalesButton = uibutton(app.PaneldeControl, 'push');
            app.Muestra2CanalesButton.Text = 'Muestra 2 Canales';
            app.TiempodemuestraEditFieldLabel = uilabel(app.PaneldeControl);
            app.TiempodemuestraEditFieldLabel.Text = 'Tiempo de muestra';
            app.TiempodemuestraEditField = uieditfield(app.PaneldeControl, 'numeric');
            app.FechaLabel = uilabel(app.PaneldeControl);
            app.FechaLabel.Text = 'Fecha:';
            app.FechaEditField = uieditfield(app.PaneldeControl, 'text');
            app.HoraLabel = uilabel(app.PaneldeControl);
            app.HoraLabel.Text = 'Hora:';
            app.HoraEditField = uieditfield(app.PaneldeControl, 'text');
            app.SealesentiempoRealPanel = uipanel(app.UIFigure);
            app.SealesentiempoRealPanel.Title = 'Señales en tiempo Real';
            app.TiempoEditFieldLabel = uilabel(app.SealesentiempoRealPanel);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.TiempoEditField = uieditfield(app.SealesentiempoRealPanel, 'text');
            app.Lamp = uilamp(app.SealesentiempoRealPanel);
            app.figFpz = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz, {'Canal 2 '; ''})
            xlabel(app.figFpz, 't (s)')
            ylabel(app.figFpz, 'uV')
            app.figFpz_2 = uiaxes(app.SealesentiempoRealPanel);
            title(app.figFpz_2, {'Canal 1 '; ''})
            xlabel(app.figFpz_2, 't (s)')
            ylabel(app.figFpz_2, 'uV')
            app.DesplieguedeCaractersticasentiemporealPanel = uipanel(app.UIFigure);
            app.DesplieguedeCaractersticasentiemporealPanel.Title = 'Despliegue de Características en tiempo real ';
            app.Canal1Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.Canal1Label.Text = 'Canal 1';
            app.EnhancedWaveLengthLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.EnhancedWaveLengthLabel.Text = 'Enhanced Wave Length';
            app.EnhancedWaveLengthEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.WaveformLengthEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.WaveformLengthEditFieldLabel.Text = 'Wave form Length';
            app.WaveformLengthEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.MaximumFractalLengthEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.MaximumFractalLengthEditFieldLabel.Text = 'Maximum Fractal Length';
            app.MaximumFractalLengthEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.skewnessEditFieldLabel = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.skewnessEditFieldLabel.Text = 'skewness';
            app.skewnessEditField = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.Canal2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.Canal2Label.Text = 'Canal 2';
            app.EnhancedWaveLengthEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.EnhancedWaveLengthEditField_2Label.Text = 'Enhanced Wave Length';
            app.EnhancedWaveLengthEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.WaveformLengthEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.WaveformLengthEditField_2Label.Text = 'Wave form Length';
            app.WaveformLengthEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.MaximumFractalLengthEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.MaximumFractalLengthEditField_2Label.Text = 'Maximum Fractal Length';
            app.MaximumFractalLengthEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.skewnessEditField_2Label = uilabel(app.DesplieguedeCaractersticasentiemporealPanel);
            app.skewnessEditField_2Label.Text = 'skewness';
            app.skewnessEditField_2 = uieditfield(app.DesplieguedeCaractersticasentiemporealPanel, 'numeric');
            app.CLASEEditFieldLabel = uilabel(app.UIFigure);
            app.CLASEEditFieldLabel.Text = 'CLASE';
            app.CLASEEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Interfaz_EMG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end